classdef Stethocare_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        APPCONFIGURATIONSLabel       matlab.ui.control.Label
        Panel2                       matlab.ui.container.Panel
        SignalTypeDropDownLabel      matlab.ui.control.Label
        SignalTypeDropDown           matlab.ui.control.DropDown
        Panel2_2                     matlab.ui.container.Panel
        LoadButton_2                 matlab.ui.control.Button
        LoadButton_3                 matlab.ui.control.Button
        LoadButton_4                 matlab.ui.control.Button
        LoadButton_5                 matlab.ui.control.Button
        Panel2_3                     matlab.ui.container.Panel
        ClassifierTypeDropDownLabel  matlab.ui.control.Label
        ClassifierTypeDropDown       matlab.ui.control.DropDown
        Panel2_4                     matlab.ui.container.Panel
        LoadButton_9                 matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        Panel_2                      matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        VisualizeSignalsTab          matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        PCGSignalLabel_2             matlab.ui.control.Label
        ModelPerformanceTab          matlab.ui.container.Tab
        ModelPerformanceSwitchLabel  matlab.ui.control.Label
        ModelPerformanceSwitch       matlab.ui.control.Switch
        TestTab                      matlab.ui.container.Tab
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        AdaBoostButton               matlab.ui.control.RadioButton
        PredictionTab                matlab.ui.container.Tab
        LabelEditFieldLabel          matlab.ui.control.Label
        LabelEditField               matlab.ui.control.EditField
        ButtonGroup                  matlab.ui.container.ButtonGroup
        TimeSignalButton             matlab.ui.control.RadioButton
        SpectrogramButton            matlab.ui.control.RadioButton
        AppstatusTextArea            matlab.ui.control.TextArea
        AppstatusTextAreaLabel       matlab.ui.control.Label
        SignalandStatusSectionLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        fs = 4000; % sampling frequency 
        nbits = 8 ; 
        n_channels = 1; 
        Data
       % model
        window_length = 5;
        window_overlap = 5;
        mdl_SVM
        mdl_GB
        recObj
    end
    
    methods (Access = private)
        
         % Button pushed function: RecordButton
        function Record(app, ~)
            
                app.recObj = audiorecorder(app.fs,app.nbits,app.n_channels); %create object
                %disp('Recording started');
                app.AppstatusTextArea.Value= 'Recording in progress';
                recordblocking(app.recObj,10); %start Recording
                app.AppstatusTextArea.Value= 'Recording completed';
               
                %disp('Recording completed');
        end

        % Button pushed function: StopButton
        function Stop(app, ~)
                y=getaudiodata(app.recObj);
                [file,path] = uiputfile('*.wav', 'Save Wav File');
                if isnumeric(file)
                    return;    %user canceled
                end
            filename = fullfile(path, file);
            %can be customised to save the hospital, and MD who performed
            %the record
            audiowrite(filename, y, app.fs, 'Title','stethoCare','Artist','My Dr.XXX');
                %app.StatusLabel.Text = 'Stop Recording';
        end

        % Button pushed function: PlotandPlayButton
        function plot_play(app, ~)
            
                y1=getaudiodata(app.recObj);
                app.Data = y1; 
                player = audioplayer(y1*2,app.fs);
                playblocking(player)
                refresh_plot(app)
                
        end
        
        function annotateTimeDomainPlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Amplitude';
            
        end
        
         function annotateSpectrogramPlot(app)
            % Update X and Y Labels
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Frequency';
            
         end
        
        % Load button pushed function: Load Audio file
         function update_plot(app, pcg_signal)
             
            if strcmp(pcg_signal,'Abnormal')
                [y,sr] = audioread('C:\Users\Asiak\Desktop\Semester_7\FY\Training_Data\Data\D1\BP1_Asthma.wav');
                app.Data = y;
                app.fs = sr;
            else
                try
                    [y,sr] = audioread('C:\Users\Asiak\Desktop\Semester_7\FY\Training_Data\Data\D1\BP30_N,N,P R M,18,F.wav');
                    app.Data = y; 
                    app.fs = sr;
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Audio Error');
                    return;
                end            
            end 
            
             switch app.ButtonGroup.SelectedObject.Text
                
                 case 'Time Signal'
                    % Build a time domain plot for the audio signal
                    plot(app.UIAxes,y)
                   
                    annotateTimeDomainPlot(app)
                    
                 case 'Spectrogram' 
                    % Build the frequency domain for the audio signal
                    [S1,F1,T1] = spectrogram(y);
                    imagesc(app.UIAxes, F1, T1,log(abs(S1'))); %plot the log spectrum
                    set(app.UIAxes,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom 
                    colorbar(app.UIAxes)
                    
                    annotateSpectrogramPlot(app)
                    
              end
            
         end
         
         % function call to classify heart sounds 
         function return_classification(app, classifier)  
             if strcmp(classifier,'AdaBoost')
                   label =  classifyHeartSounds_ensemble(app.Data, app.fs);
                   app.LabelEditField.Value = label;
                   predictionGraph(label, app.Data)
                 % Plot signal as well as actual and predicted classification
             end
         end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('C:\Users\Asiak\Desktop\Semester_7\FY\Training_Data\Data'));
            model_SVM = load('trainedModel_SVM.mat');
            model_GB = loadLearnerForCoder('trainedModel_AdaBoost');
            app.mdl_SVM = model_SVM;
            app.mdl_GB = model_GB;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {649, 649};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {296, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadButton_2
        function Record_begin(app, event)
             %global recObj
             %recObj = audiorecorder ; %create object
             Record(app); %start Recording
             %app.StatusLabel.Text = 'Recording';
        end

        % Button pushed function: LoadButton_3
        function Stop_record(app, event)
            %global recObj
            Stop(app) % Stop
            %app.StatusLabel.Text = 'Stop Recording';
        end

        % Button pushed function: LoadButton_4
        function play_and_plot(app, event)
         
            plot_play(app) % Play
           
        end

        % Button pushed function: LoadButton_5
        function LoadButtonPushed(app, event)
             % Display uigetfile dialog
              app.TabGroup.SelectedTab = app.VisualizeSignalsTab;
              [pathname,dirname] = uigetfile('*.wav');
              if (ischar(dirname))
               fname = [dirname pathname];
               [audio, sr ] = audioread(fname);
               app.Data = audio; 
               app.fs = sr;
               refresh_plot(app)
              end
              clear fullpath
     
        end

        % Selection changed function: ButtonGroup
        function refresh_plot(app, event)
           % selectedButton = app.ButtonGroup.SelectedObject;
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            audio_signal = app.Data;
             switch app.ButtonGroup.SelectedObject.Text
                
                 case 'Time Signal'
                    % Build a time domain plot for the audio signal
                    plot(app.UIAxes,audio_signal)
                   
                    annotateTimeDomainPlot(app)
                    
                 case 'Spectrogram'
                   % Build the frequency domain for the audio signal
                    [S,F,T] = spectrogram(audio_signal);
                    imagesc(app.UIAxes, F, T,log(abs(S'))); %plot the log spectrum
                    set(app.UIAxes,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom 
                    colorbar(app.UIAxes)
                    annotateSpectrogramPlot(app)
                    
            end
        end

        % Value changed function: SignalTypeDropDown
        function DropdownValueChanged(app, event)
            value = app.SignalTypeDropDown.Value;
            app.TabGroup.SelectedTab = app.VisualizeSignalsTab;
            update_plot(app, value);
        end

        % Value changed function: ModelPerformanceSwitch
        function ModelPerformance(app, event)
            value = app.ModelPerformanceSwitch.Value;
             if strcmp(value,'Confusion Matrix')
                 test = load('test_set.mat');
                 train = load('training_set.mat');
                 % confusion matrix for training 
                 %predicted_class_featsel = trainedModel1.predictFcn(test_feature_table);
                figure;
                predictedY = predict(app.mdl_GB, train.training_set);
                train_cm = confusionchart(train.training_set.class, predictedY, 'RowSummary','row-normalized');
                train_cm.Title = 'Heart Sound classification using AdaBoost';
                 % confusion matrix for ensemble
                 figure;
                 predicted_class_ensemble = predict(app.mdl_GB, test.test_set);
                 test_cm  = confusionchart(test.test_set.class, predicted_class_ensemble, 'RowSummary','row-normalized');
                 test_cm.Title = 'Heart Sound classification Test using AdaBoost';
                % Visualize model performance in heatmap
                 %labels = {'Abnormal', 'Normal'};
                % heatmap(labels, labels, conf_mat_per, 'Colormap', winter, 'ColorbarVisible','on');
            else
                % Add code for displaying an ROC curve            
            end 
            
        end

        % Value changed function: ClassifierTypeDropDown
        function Select_Classifier(app, event)
            value = app.ClassifierTypeDropDown.Value;
            app.TabGroup.SelectedTab = app.PredictionTab;
            return_classification(app, value)
        end

        % Button pushed function: LoadButton_9
        function TestButtonPushed(app, event)
           app.TabGroup.SelectedTab = app.TestTab;
           plotPredictions_ensemble
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.APPCONFIGURATIONSLabel = uilabel(app.Panel);
            app.APPCONFIGURATIONSLabel.Text = 'APP CONFIGURATIONS';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Title = 'Sample Signal';
            app.SignalTypeDropDownLabel = uilabel(app.Panel2);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.Panel2);
            app.SignalTypeDropDown.Items = {'Please Select', 'Normal', 'Abnormal'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DropdownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Please Select';
            app.Panel2_2 = uipanel(app.Panel);
            app.Panel2_2.Title = 'Acquire Signal';
            app.LoadButton_2 = uibutton(app.Panel2_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @Record_begin, true);
            app.LoadButton_2.Text = 'Record';
            app.LoadButton_3 = uibutton(app.Panel2_2, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @Stop_record, true);
            app.LoadButton_3.Text = 'Save';
            app.LoadButton_4 = uibutton(app.Panel2_2, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @play_and_plot, true);
            app.LoadButton_4.Text = 'Play';
            app.LoadButton_5 = uibutton(app.Panel2_2, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton_5.Text = 'Load Signal';
            app.Panel2_3 = uipanel(app.Panel);
            app.Panel2_3.Title = 'Classification';
            app.ClassifierTypeDropDownLabel = uilabel(app.Panel2_3);
            app.ClassifierTypeDropDownLabel.Text = 'Classifier Type';
            app.ClassifierTypeDropDown = uidropdown(app.Panel2_3);
            app.ClassifierTypeDropDown.Items = {'Please Select', 'AdaBoost'};
            app.ClassifierTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @Select_Classifier, true);
            app.ClassifierTypeDropDown.Value = 'Please Select';
            app.Panel2_4 = uipanel(app.Panel);
            app.Panel2_4.Title = 'Test Data';
            app.LoadButton_9 = uibutton(app.Panel2_4, 'push');
            app.LoadButton_9.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.LoadButton_9.Text = 'Check Test';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel_2 = uipanel(app.RightPanel);
            app.TabGroup = uitabgroup(app.Panel_2);
            app.VisualizeSignalsTab = uitab(app.TabGroup);
            app.VisualizeSignalsTab.Title = 'Visualize Signals';
            app.UIAxes = uiaxes(app.VisualizeSignalsTab);
            app.PCGSignalLabel_2 = uilabel(app.VisualizeSignalsTab);
            app.PCGSignalLabel_2.Text = 'PCG Signal';
            app.ModelPerformanceTab = uitab(app.TabGroup);
            app.ModelPerformanceTab.Title = 'Model Performance';
            app.ModelPerformanceSwitchLabel = uilabel(app.ModelPerformanceTab);
            app.ModelPerformanceSwitchLabel.Text = 'Model Performance';
            app.ModelPerformanceSwitch = uiswitch(app.ModelPerformanceTab, 'slider');
            app.ModelPerformanceSwitch.Items = {'Confusion Matrix', 'ROC'};
            app.ModelPerformanceSwitch.ValueChangedFcn = createCallbackFcn(app, @ModelPerformance, true);
            app.ModelPerformanceSwitch.Value = 'Confusion Matrix';
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.ButtonGroup_2 = uibuttongroup(app.TestTab);
            app.ButtonGroup_2.Title = 'Classifier';
            app.AdaBoostButton = uiradiobutton(app.ButtonGroup_2);
            app.AdaBoostButton.Text = 'AdaBoost';
            app.AdaBoostButton.Value = true;
            app.PredictionTab = uitab(app.TabGroup);
            app.PredictionTab.Title = 'Prediction';
            app.LabelEditFieldLabel = uilabel(app.PredictionTab);
            app.LabelEditFieldLabel.Text = 'Label ';
            app.LabelEditField = uieditfield(app.PredictionTab, 'text');
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @refresh_plot, true);
            app.ButtonGroup.Title = 'Plotting Options';
            app.TimeSignalButton = uiradiobutton(app.ButtonGroup);
            app.TimeSignalButton.Text = 'Time Signal';
            app.TimeSignalButton.Value = true;
            app.SpectrogramButton = uiradiobutton(app.ButtonGroup);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.AppstatusTextArea = uitextarea(app.Panel_2);
            app.AppstatusTextAreaLabel = uilabel(app.Panel_2);
            app.AppstatusTextAreaLabel.Text = 'App status';
            app.SignalandStatusSectionLabel = uilabel(app.Panel_2);
            app.SignalandStatusSectionLabel.Text = 'Signal and Status Section';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stethocare_copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end